classdef adcMapTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        adcMapGui                   matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ADCmapaTab                  matlab.ui.container.Tab
        Language                    matlab.ui.control.DropDown
        LanguageDropDownLabel       matlab.ui.control.Label
        objem4DdatasetLabel         matlab.ui.control.Label
        AkvizinhodnotabvalueLabel   matlab.ui.control.Label
        vyberOrganu                 matlab.ui.control.DropDown
        bValHodnota                 matlab.ui.control.Spinner
        Image                       matlab.ui.control.Image
        Konzole                     matlab.ui.control.TextArea
        vyberOkna                   matlab.ui.control.DropDown
        rezSagitKorCislo            matlab.ui.control.Spinner
        rezTransverzalniCislo       matlab.ui.control.Spinner
        popisekCorSagSlider         matlab.ui.control.Label
        rezVyberSagCor              matlab.ui.control.Slider
        saveFile                    matlab.ui.control.Button
        rezVyber                    matlab.ui.control.Slider
        b0RefButton                 matlab.ui.control.Button
        popisekAxialSlider          matlab.ui.control.Label
        bValVecValue                matlab.ui.control.Spinner
        adcMapComp                  matlab.ui.control.Button
        maskLoad                    matlab.ui.control.Button
        dwiLoad                     matlab.ui.control.Button
        frontal                     matlab.ui.control.UIAxes
        horiz                       matlab.ui.control.UIAxes
        sagit                       matlab.ui.control.UIAxes
        HypersignlnstiTab           matlab.ui.container.Tab
        rezTransverzalniCisloHyper  matlab.ui.control.Spinner
        rezVyberHyper               matlab.ui.control.Slider
        popisekAxialSlider_2        matlab.ui.control.Label
        hyperHypoSwitch             matlab.ui.control.Switch
        HypersignlnhyposignlnloiskaSwitchLabel  matlab.ui.control.Label
        Konzole_2                   matlab.ui.control.TextArea
        Image_2                     matlab.ui.control.Image
        vyberOkna2                  matlab.ui.control.DropDown
        hyperSigButton              matlab.ui.control.Button
        popisekKvantil              matlab.ui.control.Label
        kvantilValue                matlab.ui.control.Spinner
        sagitHyper                  matlab.ui.control.UIAxes
        frontalHyper                matlab.ui.control.UIAxes
        horizHyper                  matlab.ui.control.UIAxes
        VbrROITab                   matlab.ui.container.Tab
        Image_3                     matlab.ui.control.Image
        multiChoiceSwitch           matlab.ui.control.Switch
        VbrvcesouborproporovnnSwitchLabel  matlab.ui.control.Label
        nacistDalsiData             matlab.ui.control.Button
        VbrROISwitch                matlab.ui.control.Switch
        VbrROISwitchLabel           matlab.ui.control.Label
        boxplotFig                  matlab.ui.control.UIAxes
        vybraneRoi                  matlab.ui.control.UIAxes
    end


properties (Access = private)
    dwiData                                                          %promenna s difuzne vazenymi daty
    bVals                                                            %promenna s hodnotami b values
    b0Ref                                                            %objem bez difuzniho vazeni
    maska                                                            %binarni maska mozku
    hodnota                                                          %pozice b value z vektoru
    figSagAdc                                                        %sagitalni ADC mapa - obraz po prostorove transformaci
    figHorAdc                                                        %horizontalni ADC mapa - obraz po prostorove transformaci
    vyberRezu                                                        %ovladac rezu ve frontalni rovine
    vyberRezuCislo                                                   %ovladac rezu - zadani cisla
    sagCorRez                                                        %ovladac rezu v sagitalni a koronarni rovine
    sagCorRezCislo                                                   %ovladac rezu v sagitalni a koronarni rovine - zadani cisla
    neBin                                                            %import binarni/ nebinarni masky mozku
    kvantil                                                          %hodnota pro nastaveni hypersignalnich lozisek
    horizontalAdc                                                    %horizontalni ADC data                                         
    sagitalAdc                                                       %sagitalni ADC data
    frontalAdc                                                       %frontalni ADC data
    hyperSagAdc                                                      %sagitalni ADC data, hypersignalni voxely
    hyperHorAdc                                                      %horizontalni ADC data, hypersignalni voxely
    hyperFronAdc                                                     %transverzalni ADC data, hypersignalni voxely
    t                                                                %cas
    tStr                                                             %cas prevedeny na string
    adcMapReal                                                       %ADC mapa, cely objem
    prepinac                                                         %prepinac pro vyber ROI
    bValString                                                       %string hodnot b value
    adcMap                                                           %promenna s ADC mapou
    minimumFron                                                      %pomucka pro zaokrouhleni na cela cisla u vyberu frontalniho rezu
    minimumSagCor                                                    % -||- u vyberu sagitalniho a koronarniho rezu
    adc1, adc2                                                       %promenne pro porovnavani jasovych intenzit mezi nekolika merenimi
    multiChoicePozice                                                %nacitani ADC map pro porovnani
    zvoleneOkno                                                      %prepinac transverzalni, sagitalni a koronarni roviny
    zvoleneOknoHyper                                                 % -||-, hypersignalni voxely
    jmeno,jmeno1,jmeno2                                              %promenne uchovavajici jmena souboru pro boxploty
    transVisible                                                     %zobrazeni transverzalniho rezu

    %Stringy
    zrusenoStr
    succesLoad
    diffSize
    computing
    noBinMask
    binarization
    done
    compHyper
    saved
    newData
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        % Program byl vytvoren jako prakticka cast BP, Ondrej Burkot 2022
        % Programme was made as practical task of Bachelors degree final work, Ondrej Burkot 2022

        %' _________                                 __';
        %'||       \\       |\              /|      |  |';
        %'||        \\      | \            / |      |  |';
        %'||        //      ||\\          //||      |  |';
        %'||_______//       || \\        // ||      |  |';
        %'||       \\       ||  \\      //  ||      |  |';
        %'||        \\      ||   \\    //   ||      |  |';
        %'||         \\     ||    \\  //    ||      |  |';
        %'||         //     ||     \\//     ||      |  |';
        %'||        //      ||      \/      ||      |  |';
        %'||_______//       ||              ||      |__|';

        app.bValString = ['Hodnoty b value - 50 (1), 100 (2), 150 (3), 200 (4), 500 (5), 1000 ' ...
                          '(6)'];
        app.bVals = [50, 100, 150, 200, 500, 1000];
        app.adcMapReal = 0;
        app.transVisible = 1;

        % Inicializace stringu
        app.zrusenoStr = 'Operace byla zrušena uživatelem!';
        app.succesLoad = '-- Úspěšně načteno!';
        app.diffSize = 'Referenční a difuzně vážený soubor nemají stejné rozměry, ADC mapu nelze vypočítat!';
        app.computing = '-- Probíhá výpočet ADC mapy...';
        app.noBinMask = '-- Nebyla vložena maska, výpočet v celém objemu!';
        app.binarization = '-- Vložen nebinární soubor - proběhla binarizace!';
        app.done = '-- Hotovo...';
        app.compHyper = '-- Probíhá výpočet!';
        app.saved = 'Úspěšně uloženo!';
        app.newData = 'Aktuální data';
        app.dwiLoad.Text = 'Načíst DWI';
        app.b0RefButton.Text = 'Načíst b0 data';
        app.maskLoad.Text = 'Načíst masku';
        app.vyberOrganu.Items = ["Mozek"; "Prostata"];
        app.vyberOkna.Items = ["Transverzální"; "Koronární"; "Sagitální"];
        app.AkvizinhodnotabvalueLabel.Text = 'Akviziční hodnota b value';
        app.saveFile.Text = 'Uložit';
        app.adcMapComp.Text = 'Vypočíst ADC';
        app.popisekAxialSlider.Text = 'Řez - transverzální rovina';
        app.popisekCorSagSlider.Text = 'Řez - sagitální a koronární rovina';
        app.frontal.Title.String = 'ADC mapa, transverzální';
        app.horiz.Title.String = 'ADC mapa, sagitální';
        app.sagit.Title.String = 'ADC mapa, koronární';
       
        % Inicializace switche v 3. zalozce
        app.multiChoiceSwitch.Value = 'Ano';
        app.multiChoiceSwitch.Value = 'Ne';
        end

        % Button pushed function: dwiLoad
        function dwiLoadPushed(app, event)
        app.t = datetime('now');
        app.tStr = string(datestr(app.t));
        [file, path] = uigetfile({'*.nii*'; '*.dcm'});
        filename = [path, file];
        
        if path == 0
            app.dwiData = [];
            app.Konzole.Value = [app.tStr, app.zrusenoStr];
            app.dwiLoad.BackgroundColor = 'w';
        else
            app.dwiData = niftiread(filename);
            if size(app.dwiData,4) > 1
                app.Konzole.Value = [app.tStr, app.succesLoad, app.bValString];
                app.bValVecValue.Visible = 'on';
                app.objem4DdatasetLabel.Visible = 'on';

                if strcmp(app.vyberOrganu.Value, 'Mozek')
                    app.bValVecValue.Limits = [1, 6];
                else
                    app.bValVecValue.Limits = [1, 7];
                end

            else
                app.Konzole.Value = [app.tStr, app.succesLoad];
                app.bValVecValue.Visible = 'off';
                app.objem4DdatasetLabel.Visible = 'off';
                %app.dwiData = imrotate3(app.dwiData,270,[1 0 0]);
            end
            app.dwiLoad.BackgroundColor = 'g';
            if size(app.adcMap,1) > 1
                app.b0RefButton.BackgroundColor = 'y';
                app.maskLoad.BackgroundColor = 'y';
                app.saveFile.BackgroundColor = 'w';
            end
            limitRez = size(app.dwiData,3);
            limitSagCor = [size(app.dwiData,1), size(app.dwiData,2)];
            
            %v nekterych pripadech neni pocet radku a sloupcu stejny,
            %moznost vybrat rez podle toho, jestli je mensi pocet radku
            %nebo sloupcu
            if (limitSagCor(1) - limitSagCor(2)) > 0
                limitSagCor = limitSagCor(2);
            else
                limitSagCor = limitSagCor(1);
            end
            
            % Nastaveni hodnoty slideru na cele cislo
            app.rezVyber.Limits = [1, limitRez];
            app.rezVyber.MinorTicks = 1:limitRez;
            app.rezVyber.MajorTicks = [1 limitRez/2 limitRez];
            app.rezVyberHyper.Limits = [1, limitRez];
            app.rezVyberHyper.MinorTicks = 1:limitRez;
            app.rezVyberHyper.MajorTicks = [1 limitRez/2 limitRez];
            app.rezVyberSagCor.Limits = [1, limitSagCor];
            app.rezVyberSagCor.MinorTicks = 1:limitSagCor;
            app.rezVyberSagCor.MajorTicks = [1 limitSagCor/2 limitSagCor];
        end
        end

        % Button pushed function: adcMapComp
        function adcMapCompPushed(app, event)
        %%Konvertovani dat na double
        app.adcMap = 0;
        app.dwiData = double(app.dwiData);
        app.b0Ref = double(app.b0Ref);
            
        %% Zmena kurzoru na busy
        app.t = datetime('now');
        app.tStr = string(datestr(app.t));

        %% Vypocet ADC mapy
        %overeni, zda maji datasety stejny pocet rezu
        if size(app.b0Ref,3) ~= size(app.dwiData,3)
            app.Konzole.Value = [app.tStr, app.diffSize];
            app.dwiLoad.BackgroundColor = 'r';
            app.b0RefButton.BackgroundColor = 'r';
        else

            %overeni, zda byl vlozen 4D dataset
            if size(app.dwiData,4) > 1
    
                %overeni, zda byla vlozena maska
                if size(app.maska,1) > 1
                    app.Konzole.Value = [app.tStr, app.computing];
                    set(app.adcMapGui, 'pointer', 'watch')
                    drawnow;
                    for k = 1:size(app.b0Ref,1)
                        for l = 1:size(app.b0Ref,2)
                            for m = 1:size(app.b0Ref,3)
                                if app.maska(k,l,m) == 1
                                    app.adcMap(k,l,m) = (-1/app.bVals(app.hodnota))*log(app.dwiData(k,l,m,app.hodnota)/app.b0Ref(k,l,m));
                                else
                                    app.adcMap(k,l,m) = 0;                           %nutno doplnit 0 pokud neni pravda, at jsou zachovany dimenze
                                end
                            end
                        end
                    end
    
                else %vypocet ADC mapy v celem objemu
                    app.Konzole.Value = [app.tStr, app.noBinMask];
                    set(app.adcMapGui, 'pointer', 'watch')
                    drawnow;
                    for k = 1:size(app.b0Ref,1)
                        for l = 1:size(app.b0Ref,2)
                            for m = 1:size(app.b0Ref,3)
                                app.adcMap(k,l,m) = (-1/app.bVals(app.hodnota))*log(app.dwiData(k,l,m,app.hodnota)/app.b0Ref(k,l,m));
                            end
                        end
                    end
                end
    
                %nebyl nacten 4D dataset
                else
    
                    %overeni, zda byla vlozena maska - pouze u mozku
                    if size(app.maska,1) > 1
                        set(app.adcMapGui, 'pointer', 'watch')
                        drawnow;
                        app.Konzole.Value = [app.tStr, app.computing];
                        for k = 1:size(app.b0Ref,1)
                            for l = 1:size(app.b0Ref,2)
                                for m = 1:size(app.b0Ref,3)
                                    if app.maska(k,l,m) == 1
                                        app.adcMap(k,l,m) = (-1/app.bValHodnota.Value)*log(app.dwiData(k,l,m)/app.b0Ref(k,l,m));
                                    else
                                        app.adcMap(k,l,m) = 0;
                                    end
                                end
                             end
                        end
                    else
                        app.Konzole.Value = [app.tStr, app.noBinMask];
                        set(app.adcMapGui, 'pointer', 'watch')
                        drawnow;
                        for k = 1:size(app.b0Ref,1)
                            for l = 1:size(app.b0Ref,2)
                                for m = 1:size(app.b0Ref,3)
                                    app.adcMap(k,l,m) = (-1/app.bValHodnota.Value)*log(app.dwiData(k,l,m)/app.b0Ref(k,l,m));
                                end
                            end
                        end
                    end
            end

            set(app.adcMapGui, 'pointer', 'arrow')

        app.adcMapReal = sqrt(real(app.adcMap).^2 + imag(app.adcMap).^2);        %velikost komplexniho cisla
        %nahrazeni NaN hodot nulami, stejne jasove kodovani --> nedela problemy
        for k = 1:size(app.adcMap,1)
            for l = 1:size(app.adcMap,2)
                for m = 1:size(app.adcMap,3)
                    if app.adcMapReal(k,l,m) == Inf
                        app.adcMapReal(k,l,m) = 0;
                    end
                end
            end
        end

        %inicializace promenne pro identifikaci hypersignalnich casti
        app.frontalAdc = zeros(size(app.adcMapReal,1),size(app.adcMapReal,2),size(app.adcMapReal,3));
        for k = 1:size(app.adcMapReal,1)
            for l = 1:size(app.adcMapReal,2)
                for m = 1:size(app.adcMapReal,3)
                    if isnan(app.adcMapReal(k,l,m))                     %pokud je na pozici k,l,m NaN, prekodovat na 0
                        app.frontalAdc(k,l,m) = 0;
                    else
                        app.frontalAdc(k,l,m) = app.adcMapReal(k,l,m);    %pokud ne, ponechat puvodni hodnotu
                    end
                end
            end
        end            
    
            %% Zobrazeni, zdroj https://www.youtube.com/watch?v=uXqYvjlc__8
            dimAdc = size(app.adcMapReal);
    
            app.horizontalAdc = reshape(app.adcMapReal(:,app.sagCorRez,:), [dimAdc(1) dimAdc(3)]);
            app.sagitalAdc = reshape(app.adcMapReal(app.sagCorRez,:,:), [dimAdc(2) dimAdc(3)]);
    
            app.figSagAdc = app.sagitalAdc;
            app.figHorAdc = app.horizontalAdc;
    
            cla(app.horiz)
            cla(app.frontal)
            cla(app.sagit)
            imshow(app.adcMapReal(:,:,app.vyberRezu), [], 'Parent', app.frontal)
            colormap(app.frontal, 'jet')
            colorbar(app.frontal)
            app.t = datetime('now');
            app.tStr = string(datestr(app.t));
            app.Konzole.Value = [app.tStr, app.done];
        end

        app.rezVyberHyper.Value = app.rezVyber.Value;
        app.rezTransverzalniCisloHyper.Value = app.rezVyber.Value;
        end

        % Button pushed function: maskLoad
        function maskLoadPushed(app, event)
        app.t = datetime('now');
        app.tStr = string(datestr(app.t));
        [file, path] = uigetfile({'*.nii*'; '*.dcm'});
        filename = [path, file];
       
        %overeni, zda nebylo zruseno nacteni
        if path == 0
            app.maska = [];
            app.Konzole.Value = [app.tStr, app.zrusenoStr];
            app.maskLoad.BackgroundColor = 'w';
        else
            app.maska = niftiread(filename);
            if size(app.dwiData,4) > 1
                app.Konzole.Value = [app.tStr, app.succesLoad, app.bValString];
            else
                app.Konzole.Value = [app.tStr, app.succesLoad];
            end
        end

        %overeni, zda byla nactena binarni data
        app.neBin = find(app.maska>1);

        %overeni, zda byl nahran binarni/ nebinarni soubor
        if size(app.maska,1) > 10
            if (size(app.neBin,1) > 1) || (size(app.neBin,2) > 1)
                thresh = graythresh(app.maska);
                app.maska = imbinarize(app.maska, thresh);
                app.maskLoad.BackgroundColor = 'y';
                if size(app.dwiData,4) > 1
                    app.Konzole.Value = [app.tStr, app.binarization, app.bValString];
                else
                    app.Konzole.Value = [app.tStr, app.binarization];
                end
            else
              app.maskLoad.BackgroundColor = 'g';
              if size(app.dwiData,4) > 1
                app.Konzole.Value = [app.tStr, app.succesLoad, app.bValString];
              else
                  app.Konzole.Value = [app.tStr, app.succesLoad];
              end
            end
        end
        % Pokud se nejedna o binarni masku, bude mozkova tkan
        % binarizovana pomoci Otsu metody, lepsi nacitat masku ziskanou
        % s pomoci FSL BET           
        end

        % Value changed function: bValVecValue
        function bValSlider(app, event)
        app.hodnota = app.bValVecValue.Value;
        end

        % Value changed function: rezVyber
        function rezVyberValueChanged(app, event)
        app.vyberRezu = app.rezVyber.Value;  
        [~, app.minimumFron] = min(abs(app.vyberRezu - event.Source.MinorTicks(:)));
        event.Source.Value = event.Source.MinorTicks(app.minimumFron);
        app.vyberRezu = event.Source.MinorTicks(app.minimumFron);

        app.rezTransverzalniCislo.Value = app.vyberRezu;
        end

        % Button pushed function: b0RefButton
        function b0RefLoad(app, event)
        app.t = datetime('now');
        app.tStr = string(datestr(app.t));
        [file, path] = uigetfile({'*.nii*'; '*.dcm'});
        filename = [path, file];

        if path == 0
            app.b0Ref = [];
            app.Konzole.Value = [app.tStr, app.zrusenoStr];
            app.b0RefButton.BackgroundColor = 'w';
        else
            app.b0Ref = niftiread(filename);
            if size(app.dwiData,4) > 1
                app.Konzole.Value = [app.tStr, app.succesLoad, app.bValString];
            else
                app.Konzole.Value = [app.tStr, app.succesLoad];
                %app.b0Ref = imrotate3(app.b0Ref,270,[1 0 0]);
            end
            app.b0RefButton.BackgroundColor = 'g';
        end
        end

        % Button pushed function: hyperSigButton
        function hyperSigButtonPushed(app, event)
        % nastaveni vychozi hodnoty kvantilu
        switch app.hyperHypoSwitch.Value
            case 'Hypersignální'
                if isempty(app.kvantil)
                    app.kvantil = 0.9;
                end
                
                % nastaveni mezi
                hyperSagMez = quantile(app.sagitalAdc, app.kvantil, 'all');
                hyperHorMez = quantile(app.horizontalAdc, app.kvantil, 'all');
                hyperFronMez = quantile(app.frontalAdc, app.kvantil, 'all');

                app.t = datetime('now');
                app.tStr = string(datestr(app.t));
                app.Konzole_2.Value = [app.tStr, app.compHyper];
                set(app.adcMapGui, 'pointer', 'watch')
                drawnow;

                % porovnani kazdeho pixelu s mezi v prislusne rovine
                app.hyperFronAdc = zeros(size(app.frontalAdc,1),size(app.frontalAdc,2),size(app.frontalAdc,3));
                for k = 1:size(app.frontalAdc,1)
                    for l = 1:size(app.frontalAdc,2)
                        for m = 1:size(app.frontalAdc,3)
                            if app.frontalAdc(k,l,m) >= hyperFronMez
                                app.hyperFronAdc(k,l,m) = app.frontalAdc(k,l,m);
                            end
                        end
                    end
                end
                app.hyperSagAdc = zeros(size(app.sagitalAdc,1),size(app.sagitalAdc,2));
                for k = 1:size(app.sagitalAdc,1)
                    for l = 1:size(app.sagitalAdc,2)
                        if app.sagitalAdc(k,l) >= hyperSagMez
                            app.hyperSagAdc(k,l) = app.sagitalAdc(k,l);
                        end
                    end
                end
                app.hyperHorAdc = zeros(size(app.horizontalAdc,1),size(app.horizontalAdc,2));
                for k = 1:size(app.horizontalAdc,1)
                    for l = 1:size(app.horizontalAdc,2)
                        if app.horizontalAdc(k,l) >= hyperHorMez
                            app.hyperHorAdc(k,l) = app.horizontalAdc(k,l);
                        end
                    end
                end
                
                % zobrazeni vysledku
                imshow(app.hyperFronAdc(:,:,app.vyberRezu), [], 'Parent', app.frontalHyper)
                colormap(app.frontalHyper, 'jet')
                colorbar(app.frontalHyper)
                cla(app.sagitHyper)
                cla(app.horizHyper)
                set(app.adcMapGui, 'pointer', 'arrow')
                app.t = datetime('now');
                app.tStr = string(datestr(app.t));
                app.Konzole_2.Value = [app.tStr, app.done];

            case 'Hyposignální'
                if app.kvantilValue.Value > 0.5
                    app.kvantilValue.Value = 0.1;
                    app.kvantil = 0.1;
                else
                    app.kvantil = app.kvantilValue.Value;
                end

                hyperSagMez = quantile(app.sagitalAdc, app.kvantil, 'all');
                hyperHorMez = quantile(app.horizontalAdc, app.kvantil, 'all');
                hyperFronMez = quantile(app.frontalAdc, app.kvantil, 'all');

                app.t = datetime('now');
                app.tStr = string(datestr(app.t));
                app.Konzole_2.Value = [app.tStr, app.compHyper];
                set(app.adcMapGui, 'pointer', 'watch')
                drawnow;
                app.hyperFronAdc = zeros(size(app.frontalAdc,1),size(app.frontalAdc,2));
                for k = 1:size(app.frontalAdc,1)
                    for l = 1:size(app.frontalAdc,2)
                        if app.frontalAdc(k,l) <= hyperFronMez
                            app.hyperFronAdc(k,l) = app.frontalAdc(k,l);
                        end
                    end
                end
                app.hyperSagAdc = zeros(size(app.sagitalAdc,1),size(app.sagitalAdc,2));
                for k = 1:size(app.sagitalAdc,1)
                    for l = 1:size(app.sagitalAdc,2)
                        if app.sagitalAdc(k,l) <= hyperSagMez
                            app.hyperSagAdc(k,l) = app.sagitalAdc(k,l);
                        end
                    end
                end
                app.hyperHorAdc = zeros(size(app.horizontalAdc,1),size(app.horizontalAdc,2));
                for k = 1:size(app.horizontalAdc,1)
                    for l = 1:size(app.horizontalAdc,2)
                        if app.horizontalAdc(k,l) <= hyperHorMez
                            app.hyperHorAdc(k,l) = app.horizontalAdc(k,l);
                        end
                    end
                end

                imshow(app.hyperFronAdc, [], 'Parent', app.frontalHyper)
                colormap(app.frontalHyper, 'jet')
                colorbar(app.frontalHyper)
                cla(app.sagitHyper)
                cla(app.horizHyper)
                set(app.adcMapGui, 'pointer', 'arrow')
                app.t = datetime('now');
                app.tStr = string(datestr(app.t));
                app.Konzole_2.Value = [app.tStr, app.done];
        end
        end

        % Value changed function: kvantilValue
        function kvantilValueValueChanged(app, event)
        app.kvantil = app.kvantilValue.Value;
        end

        % Button pushed function: saveFile
        function saveFileButtonPushed(app, event)
        [file, path] = uiputfile({'*.nii'; '*.dcm'});
        filename = [path, file];
        app.t = datetime('now');
        app.tStr = string(datestr(app.t));
        
        if path == 0
            app.Konzole.Value = [app.tStr, app.zrusenoStr];
            app.saveFile.BackgroundColor = 'w';
        else
            niftiwrite(imrotate3(app.adcMapReal,270,[0 0 1]), filename);
            app.Konzole.Value = [app.tStr, app.saved];
            app.saveFile.BackgroundColor = 'g';
        end
        end

        % Value changed function: VbrROISwitch
        function VbrROISwitchValueChanged(app, event)
        app.prepinac = app.VbrROISwitch.Value;
        cla(app.vybraneRoi)
        
        switch app.prepinac
            case 'Ano'
                figure('Name','Výběr ROI', 'WindowState','maximized')
                rezVyberRoi = app.adcMapReal(:,:,app.vyberRezu);
                imshow(rezVyberRoi, [])
                colormap(jet)
                roi = drawpolygon('color', 'r');
                maskaRoi = createMask(roi);
                assignin('base','maskaRoi',maskaRoi);
                close Figure 1

                %maska, pokud byly zadany dva soubory pro porovnani
                if (size(app.adc1,1) > 1) && (size(app.adc2,1) > 1)
                    for k = 1:size(app.adcMapReal,1)
                        for l = 1:size(app.adcMapReal,2)
                            %kopirovani vybrane oblasti do matice pomoci binarni masky
                            if maskaRoi(k,l) == 1
                                vyberHodnotCerstveData(k,l) = app.adcMapReal(k,l,app.vyberRezu);
                                vyberHodnotNactenaData1(k,l) = app.adc1(k,l,app.vyberRezu);
                                vyberHodnotNactenaData2(k,l) = app.adc2(k,l,app.vyberRezu);
                            else
                                vyberHodnotCerstveData(k,l) = NaN;
                                vyberHodnotNactenaData1(k,l) = NaN;
                                vyberHodnotNactenaData2(k,l) = NaN;
                            end
                        end
                    end
                %maska, pokud byl zadan jeden soubor pro porovnani
                elseif (size(app.adc1,1) > 1) && (size(app.adc2,1) == 0)
                    vyberHodnotNactenaData2 = [];
                    for k = 1:size(app.adcMapReal,1)
                        for l = 1:size(app.adcMapReal,2)
                            if maskaRoi(k,l) == 1
                                vyberHodnotCerstveData(k,l) = app.adcMapReal(k,l,app.vyberRezu);
                                vyberHodnotNactenaData1(k,l) = app.adc1(k,l,app.vyberRezu);
                            else
                                vyberHodnotCerstveData(k,l) = NaN;
                                vyberHodnotNactenaData1(k,l) = NaN;
                            end
                        end
                    end
                %maska, pokud nebyl zadana zadny soubor pro porovnani
                elseif (size(app.adc1,1) == 0) && (size(app.adc2,1) == 0)
                    vyberHodnotNactenaData1 = [];
                    vyberHodnotNactenaData2 = [];
                    for k = 1:size(app.adcMapReal,1)
                        for l = 1:size(app.adcMapReal,2)
                            if maskaRoi(k,l) == 1
                                vyberHodnotCerstveData(k,l) = app.adcMapReal(k,l,app.vyberRezu);
                            else
                                vyberHodnotCerstveData(k,l) = NaN;
                            end
                        end
                    end
                end

               %filtrace NaN ktere Boxplot zkreslily
               if (size(app.adc1,1) > 1) && (size(app.adc2,1) > 1)
                   for k = 1:size(vyberHodnotCerstveData,1)
                       for l = 1:size(vyberHodnotCerstveData,2)
                           if isnan(vyberHodnotCerstveData(k,l))
                           else
                               vyberHodnotCerstveData(k,l) = vyberHodnotCerstveData(k,l);
                               vyberHodnotNactenaData1(k,l) = vyberHodnotNactenaData1(k,l);
                               vyberHodnotNactenaData2(k,l) = vyberHodnotNactenaData2(k,l);                        
                           end
                       end
                   end
               elseif (size(app.adc1,1) > 1) && (size(app.adc2,1) == 0)
                   for k = 1:size(vyberHodnotCerstveData,1)
                       for l = 1:size(vyberHodnotCerstveData,2)
                           if isnan(vyberHodnotCerstveData(k,l))
                           else
                               vyberHodnotCerstveData(k,l) = vyberHodnotCerstveData(k,l);
                               vyberHodnotNactenaData1(k,l) = vyberHodnotNactenaData1(k,l);
                           end
                       end
                   end
               elseif (size(app.adc1,1) == 0) && (size(app.adc2,1) == 0)
                   for k = 1:size(vyberHodnotCerstveData,1)
                       for l = 1:size(vyberHodnotCerstveData,2)
                           if isnan(vyberHodnotCerstveData(k,l))
                           else
                               vyberHodnotCerstveData(k,l) = vyberHodnotCerstveData(k,l);
                           end
                       end
                   end
               end

               hold(app.vybraneRoi, 'on')
               maskaRoiFig = imshow(maskaRoi, 'Parent', app.vybraneRoi);
               set(maskaRoiFig, 'AlphaData', 1);
               pozadi = imshow(rezVyberRoi, [], 'Parent', app.vybraneRoi);
               set(pozadi, 'AlphaData', 0.4)
               hold(app.vybraneRoi, 'off')
                
               if (size(vyberHodnotNactenaData1,1) > 1) && (size(vyberHodnotNactenaData2,1) > 1)
                   vyberHodnot1 = reshape(vyberHodnotCerstveData,[],1);
                   vyberHodnot2 = reshape(vyberHodnotNactenaData1,[],1);
                   vyberHodnot3 = reshape(vyberHodnotNactenaData2,[],1);
                   vyberHodnot = [vyberHodnot1, vyberHodnot2, vyberHodnot3];
                   assignin("base","vyberHodnot3",vyberHodnot);
                   boxplot(vyberHodnot, {app.newData, app.jmeno1, app.jmeno2}, 'Parent', app.boxplotFig);
               elseif (size(vyberHodnotNactenaData1,1) > 1) && (size(vyberHodnotNactenaData2,1) == 0)
                   vyberHodnot1 = reshape(vyberHodnotCerstveData,[],1);
                   vyberHodnot2 = reshape(vyberHodnotNactenaData1,[],1);
                   vyberHodnot = [vyberHodnot1, vyberHodnot2];
                   assignin("base","vyberHodnot2",vyberHodnot);
                   boxplot(vyberHodnot, {app.newData, app.jmeno}, 'Parent', app.boxplotFig);
               elseif (size(app.adc1,1) == 0) && (size(app.adc2,1) == 0)
                   vyberHodnot = reshape(vyberHodnotCerstveData,[],1);
                   assignin("base","vyberHodnot1",vyberHodnot);
                   boxplot(vyberHodnot, {app.newData}, 'Parent', app.boxplotFig);
               end
               app.VbrROISwitch.Value = 'Ne';
            case 'Ne'
        end
        end

        % Value changed function: rezVyberSagCor
        function rezVyberSagCorValueChanged(app, event)
            app.sagCorRez = app.rezVyberSagCor.Value;
            [~, app.minimumSagCor] = min(abs(app.sagCorRez - event.Source.MinorTicks(:)));
            event.Source.Value = event.Source.MinorTicks(app.minimumSagCor);
            app.sagCorRez = event.Source.MinorTicks(app.minimumSagCor);

            app.rezSagitKorCislo.Value = app.sagCorRez;
        end

        % Button pushed function: nacistDalsiData
        function nacistDalsiDataButtonPushed(app, event)
        %nacteni vice souboru, je nutne, aby byly soubory ve stejnem
        %adresari, jinak nepujdou zvolit
        
        if ischar(app.multiChoiceSwitch.Value)
            app.multiChoiceSwitch.Value = app.multiChoiceSwitch.Value;
        else
            app.multiChoiceSwitch.Value = 'Ne';
        end

        switch app.multiChoiceSwitch.Value
            case 'Ano'
                %smycka je nastavena pro nacteni dalsich dvou souboru
                %pro vetsi pocet nacitanych souboru je nutne kod upravit!

                %pri zvoleni vice, nez 2 souboru, budou nacteny jen prvni
                %dva!
                [app.jmeno, path] = uigetfile('*.nii', 'MultiSelect', 'on');
                %zruseni nacteni dat
                if path == 0
                   app.nacistDalsiData.BackgroundColor = 'w';
                   app.adc1 = [];
                   app.adc2 = [];
                %data byla nactena
                else
                    if size(app.jmeno,2) > 1                        
                        jmeno11 = string(app.jmeno(1));
                        app.jmeno1 = char(app.jmeno(1));
                        jmeno22 = string(app.jmeno(2));
                        app.jmeno2 = char(app.jmeno(2));
                        filename1 = strcat(path, jmeno11);
                        app.adc1 = niftiread(filename1);
                        filename2 = strcat(path, jmeno22);
                        app.adc2 = niftiread(filename2);
                        app.nacistDalsiData.BackgroundColor = 'g';
                    end
                end
            case 'Ne'
                [app.jmeno, path] = uigetfile('*.nii');
                %zruseni nacteni dat
                if path == 0
                   app.nacistDalsiData.BackgroundColor = 'w';
                   app.adc1 = [];
                   app.adc2 = [];
                %data byla nactena
                else
                    filename = [path, app.jmeno];
                    app.adc1 = niftiread(filename);
                    app.adc2 = [];    
                    app.nacistDalsiData.BackgroundColor = 'g';
                end
        end
        end

        % Value changed function: rezTransverzalniCislo
        function rezTransverzalniCisloValueChanged(app, event)
            app.vyberRezuCislo = app.rezTransverzalniCislo.Value;
            
            %zmena hodnoty rezVyber podle zadani z vyberRezuCislo
            app.rezVyber.Value = app.rezVyber.MinorTicks(app.vyberRezuCislo);
            app.vyberRezu = app.vyberRezuCislo;
            
            % Zobrazeni rezu podle zmeny hodnoty spinneru, pokud jsou k
            % dispozici ADC data
            if app.transVisible == 1 && size(app.adcMapReal,3) > 1
                imshow(app.adcMapReal(:,:,app.vyberRezu), [], 'Parent', app.frontal)
                colormap(app.frontal, 'jet')
                colorbar(app.frontal)
            end
        end

        % Value changed function: rezSagitKorCislo
        function rezSagitKorCisloValueChanged(app, event)
            app.sagCorRezCislo = app.rezSagitKorCislo.Value;

            %zmena hodnoty rezVyber podle zadani z vyberRezuCislo            
            app.rezVyberSagCor.Value = app.rezVyberSagCor.MinorTicks(app.sagCorRezCislo);
            app.sagCorRez = app.sagCorRezCislo;
        end

        % Value changed function: vyberOkna
        function vyberOknaValueChanged(app, event)
            app.zvoleneOkno = app.vyberOkna.Value;
            app.transVisible = strcmp(app.zvoleneOkno, 'Transverzální');
            horizVisible = strcmp(app.zvoleneOkno, 'Koronární');
            sagitVisible = strcmp(app.zvoleneOkno, 'Sagitální');
            
            if app.transVisible == 1
                app.frontal.Visible = 'on';
                app.horiz.Visible = 'off';
                app.horiz.Title = [];
                colorbar(app.horiz, 'off')
                cla(app.horiz)
                app.sagit.Visible = 'off';
                app.sagit.Title = [];
                colorbar(app.sagit, 'off')
                cla(app.sagit)
                
                if size(app.adcMapReal,1) > 1
                    imshow(app.adcMapReal(:,:,app.vyberRezu), [], 'Parent', app.frontal)
                    colormap(app.frontal, 'jet')
                    colorbar(app.frontal)
                    app.frontal.Title.String = "Transverzální řez";
                end
             elseif horizVisible == 1
                app.frontal.Visible = 'off';
                app.frontal.Title = [];
                colorbar(app.frontal, 'off')
                cla(app.frontal)
                app.horiz.Visible = 'off';
                app.horiz.Title = [];
                colorbar(app.horiz, 'off')
                cla(app.horiz)
                app.sagit.Visible = 'on';
                
                if size(app.figSagAdc,1) > 1
                    imshow(app.figSagAdc, [], 'Parent', app.sagit)
                    colormap(app.sagit,'jet')
                    colorbar(app.sagit)
                    app.sagit.Title.String = "Koronární řez";
                end
            elseif sagitVisible == 1
                app.frontal.Visible = 'off';
                app.frontal.Title = [];
                colorbar(app.frontal, 'off')
                cla(app.frontal)
                cla(app.horiz)
                app.horiz.Visible = 'on';
                app.sagit.Visible = 'off';
                app.sagit.Title = [];
                colorbar(app.sagit, 'off')
                cla(app.sagit)
                
                if size(app.figHorAdc,1) > 1
                    imshow(app.figHorAdc, [], 'Parent', app.horiz)
                    colormap(app.horiz,'jet')
                    colorbar(app.horiz)
                    app.horiz.Title.String = "Sagitální řez";
                end
            end
        end

        % Value changed function: vyberOkna2
        function vyberOkna2ValueChanged(app, event)
            app.zvoleneOknoHyper = app.vyberOkna2.Value;
            app.transVisible = strcmp(app.zvoleneOknoHyper, 'Transverzální');
            horizVisible = strcmp(app.zvoleneOknoHyper, 'Koronární');
            sagitVisible = strcmp(app.zvoleneOknoHyper, 'Sagitální');
            
            if app.transVisible == 1
                app.frontalHyper.Visible = 'on';
                app.horizHyper.Visible = 'off';
                app.horizHyper.Title = [];
                colorbar(app.horizHyper, 'off')
                cla(app.horizHyper)
                app.sagitHyper.Visible = 'off';
                app.sagitHyper.Title = [];
                colorbar(app.sagitHyper, 'off')
                cla(app.sagitHyper)

                if size(app.hyperFronAdc,1) > 1
                    imshow(app.hyperFronAdc(:,:,app.vyberRezu), [], 'Parent', app.frontalHyper)
                    colormap(app.frontalHyper, 'jet')
                    colorbar(app.frontalHyper)
                    app.frontalHyper.Title.String = "Transverzální řez, hypersignální voxely";
                end
            elseif sagitVisible == 1
                app.frontalHyper.Visible = 'off';
                app.frontalHyper.Title = [];
                colorbar(app.frontalHyper, 'off')
                cla(app.frontalHyper)
                app.horizHyper.Visible = 'off';
                app.horizHyper.Title = [];
                colorbar(app.horizHyper, 'off')
                cla(app.horizHyper)
                app.sagitHyper.Visible = 'on';

                if size(app.hyperHorAdc,1) > 1
                    imshow(app.hyperHorAdc, [], 'Parent', app.sagitHyper)
                    colormap(app.sagitHyper,'jet')
                    colorbar(app.sagitHyper)
                    app.sagitHyper.Title.String = "Sagítální řez, hypersignální voxely";
                end
            elseif horizVisible == 1
                app.frontalHyper.Visible = 'off';
                app.frontalHyper.Title = [];
                colorbar(app.frontalHyper, 'off')
                cla(app.frontalHyper)
                app.horizHyper.Visible = 'on';
                app.sagitHyper.Visible = 'off';
                app.sagitHyper.Title = [];
                colorbar(app.sagitHyper, 'off')
                cla(app.sagitHyper)

                if size(app.hyperSagAdc,1) > 1
                    imshow(app.hyperSagAdc, [], 'Parent', app.horizHyper)
                    colormap(app.horizHyper,'jet')
                    colorbar(app.horizHyper)
                    app.horizHyper.Title.String = "Koronární řez, hypersignální voxely";
                end
            end
        end

        % Value changed function: vyberOrganu
        function vyberOrganuValueChanged(app, event)
        switch app.vyberOrganu.Value
            case 'Mozek'
                app.bVals = [50, 100, 150, 200, 500, 1000];
                app.bValString = ['Hodnoty b value - 50 (1), 100 (2), 150 (3), 200 (4), 500 (5), 1000 ' ...
                                   '(6)'];
                app.bValHodnota.Value = 1000;
                app.Konzole.Value = 'Práce se snímky mozku.';
            case 'Prostata'
                app.bVals = [50, 100, 150, 200, 400, 800, 1400];
                app.bValString = ['Hodnoty b value - 50 (1), 100 (2), 150 (3), 200 (4), 400 (5), 800 (6), 1400 (7)'];
                app.bValHodnota.Value = 1400;
                app.Konzole.Value = 'Práce se snímky prostaty.';
        end
        end

        % Value changing function: rezVyber
        function rezVyberValueChanging(app, event)
            % Prohlizeni celeho objemu ve smycce
            aktualniRez = event.Value;
            
            % Zaokrouhleni hodnoty rezu - slider iteruje pres int hodnoty
            dolniRozdil = aktualniRez - round(aktualniRez);
            horniRozdil = aktualniRez - ceil(aktualniRez);
            
            % Zaokrouhleni hodnoty rezu na nizsi/ vyssi cislo podle rozdilu
            if dolniRozdil - horniRozdil > 0
                aktualniRez = ceil(aktualniRez);
            else
                aktualniRez = round(aktualniRez);
            end
            
            % Zobrazeni vybraneho rezu
            if app.frontalHyper.Visible == 1 && size(app.adcMapReal,3) > 1
                imshow(app.adcMapReal(:,:,aktualniRez), [], 'Parent', app.frontal)
                colormap(app.frontal, 'jet')
            end

            app.rezTransverzalniCislo.Value = aktualniRez;
            app.rezVyberHyper.Value = aktualniRez;
            app.rezTransverzalniCisloHyper.Value = aktualniRez;
        end

        % Value changing function: rezVyberHyper
        function rezVyberHyperValueChanging(app, event)
            rezHyper = event.Value;

            dolniRozdil = rezHyper - round(rezHyper);
            horniRozdil = rezHyper - ceil(rezHyper);

            % Zaokrouhleni hodnoty rezu na nizsi/ vyssi cislo podle rozdilu
            if dolniRozdil - horniRozdil > 0
                rezHyper = ceil(rezHyper);
            else
                rezHyper = round(rezHyper);
            end

            if app.transVisible == 1 && size(app.hyperFronAdc,3) > 1
                imshow(app.hyperFronAdc(:,:,rezHyper), [], 'Parent', app.frontalHyper)
                colormap(app.frontalHyper, 'jet')
                colorbar(app.frontalHyper)
            end

            app.rezTransverzalniCisloHyper.Value = rezHyper;
            app.rezVyber.Value = rezHyper;
            app.rezTransverzalniCislo.Value = rezHyper;
        end

        % Value changed function: rezTransverzalniCisloHyper
        function rezTransverzalniCisloHyperValueChanged(app, event)
            rez = app.rezTransverzalniCisloHyper.Value;
            app.rezVyberHyper.Value = rez;

            if app.transVisible == 1 && size(app.hyperFronAdc,3) > 1
                imshow(app.hyperFronAdc(:,:,rez), [], 'Parent', app.frontalHyper)
                colormap(app.frontalHyper, 'jet')
                colorbar(app.frontalHyper)
            end
        end

        % Value changed function: rezVyberHyper
        function rezVyberHyperValueChanged(app, event)
        rez = app.rezVyberHyper.Value;
              
        [~, minimum] = min(abs(rez - event.Source.MinorTicks(:)));
        event.Source.Value = event.Source.MinorTicks(minimum);
        end

        % Value changed function: Language
        function LanguageValueChanged(app, event)
            if size(app.adcMapReal,3) == 1
                if app.Language.Value == "Česky"
                    app.zrusenoStr = 'Operace byla zrušena uživatelem!';
                    app.succesLoad = '-- Úspěšně načteno!';
                    app.diffSize = 'Referenční a difuzně vážený soubor nemají stejné rozměry, ADC mapu nelze vypočítat!';
                    app.computing = '-- Probíhá výpočet ADC mapy...';
                    app.noBinMask = '-- Nebyla vložena maska, výpočet v celém objemu!';
                    app.binarization = '-- Vložen nebinární soubor - proběhla binarizace!';
                    app.done = '-- Hotovo...';
                    app.compHyper = '-- Probíhá výpočet!';
                    app.saved = 'Úspěšně uloženo!';
                    app.newData = 'Aktuální data';
                    app.dwiLoad.Text = 'Načíst DWI';
                    app.b0RefButton.Text = 'Načíst b0 data';
                    app.maskLoad.Text = 'Načíst masku';
                    app.vyberOrganu.Items = ["Mozek"; "Prostata"];
                    app.vyberOkna.Items = ["Transverzální"; "Koronární"; "Sagitální"];
                    app.AkvizinhodnotabvalueLabel.Text = 'Akviziční hodnota b value';
                    app.saveFile.Text = 'Uložit';
                    app.adcMapComp.Text = 'Vypočíst ADC';
                    app.popisekAxialSlider.Text = 'Řez - transverzální rovina';
                    app.popisekCorSagSlider.Text = 'Řez - sagitální a koronární rovina';
                    app.frontal.Title.String = 'ADC mapa, transverzální';
                    app.horiz.Title.String = 'ADC mapa, sagitální';
                    app.sagit.Title.String = 'ADC mapa, koronární';
                else
                    app.zrusenoStr = 'Operation was cancelled by user!';
                    app.succesLoad = '-- Succesfully loaded!';
                    app.diffSize = 'DWI and b0 data do not have same number of slices, please register!';
                    app.computing = '-- Computing ADC map...';
                    app.noBinMask = 'Binary mask was not loaded, computing ADC map in whole volume!';
                    app.binarization = '-- Loaded non-binary data - binarization!';
                    app.done = '-- Done...';
                    app.compHyper = '-- Computing!';
                    app.saved = 'Successfully saved!';
                    app.newData = 'Last measurement';
                    app.dwiLoad.Text = 'Load DWI';
                    app.b0RefButton.Text = 'Load b0 data';
                    app.maskLoad.Text = 'Load binary mask';
                    app.vyberOrganu.Items = ["Brain"; "Prostate"];
                    app.vyberOkna.Items = ["Transverse", "Coronary", "Sagittal"];
                    app.AkvizinhodnotabvalueLabel.Text = 'b value';
                    app.saveFile.Text = 'Save';
                    app.adcMapComp.Text = 'Compute ADC';
                    app.popisekAxialSlider.Text = 'Slice - transverse plane';
                    app.popisekCorSagSlider.Text = 'Slice - sagittal and coronar plane';
                    app.frontal.Title.String = 'ADC mapy, transverse';
                    app.horiz.Title.String = 'ADC map, coronary';
                    app.sagit.Title.String = 'ADC map, sagittal';
                end
            else
                app.Konzole.Value = 'Jazyk lze změnit pouze před načtením dat!';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.adcMapGui = uifigure('Visible', 'off');
            app.adcMapGui.Name = 'ADC map tool';
            app.adcMapGui.Icon = 'logoVsb.jpg';
            app.TabGroup = uitabgroup(app.adcMapGui);
            app.ADCmapaTab = uitab(app.TabGroup);
            app.ADCmapaTab.Title = 'ADC mapa';
            app.sagit = uiaxes(app.ADCmapaTab);
            title(app.sagit, 'ADC mapa, koronární')
            zlabel(app.sagit, 'Z')
            app.sagit.Visible = 'off';
            app.horiz = uiaxes(app.ADCmapaTab);
            title(app.horiz, 'ADC mapa, sagitální')
            zlabel(app.horiz, 'Z')
            app.horiz.Visible = 'off';
            app.frontal = uiaxes(app.ADCmapaTab);
            title(app.frontal, 'ADC mapa, transverzální')
            zlabel(app.frontal, 'Z')
            app.dwiLoad = uibutton(app.ADCmapaTab, 'push');
            app.dwiLoad.ButtonPushedFcn = createCallbackFcn(app, @dwiLoadPushed, true);
            app.dwiLoad.Tooltip = {'Načtení difuzně vážených snímků'; ''; 'Při zadání 4D datasetu se zobrazí nápověda pro zvolení objemu'};
            app.dwiLoad.Text = 'Načíst DWI';
            app.maskLoad = uibutton(app.ADCmapaTab, 'push');
            app.maskLoad.ButtonPushedFcn = createCallbackFcn(app, @maskLoadPushed, true);
            app.maskLoad.Tooltip = {'Načtení masky, není potřeba zvolit'};
            app.maskLoad.Text = {'Načíst masku'; 'mozku po BET'};
            app.adcMapComp = uibutton(app.ADCmapaTab, 'push');
            app.adcMapComp.ButtonPushedFcn = createCallbackFcn(app, @adcMapCompPushed, true);
            app.adcMapComp.Text = {'Vypočíst ADC'; 'mapu'};
            app.bValVecValue = uispinner(app.ADCmapaTab);
            app.bValVecValue.Limits = [1 6];
            app.bValVecValue.ValueChangedFcn = createCallbackFcn(app, @bValSlider, true);
            app.bValVecValue.Visible = 'off';
            app.bValVecValue.Tooltip = {'pozice b value z'; 'vektoru pro'; 'výpočet ADC mapy'; ''; 'vyplnit pouze při 4D datasetu'};
            app.bValVecValue.Value = 1;
            app.popisekAxialSlider = uilabel(app.ADCmapaTab);
            app.popisekAxialSlider.Text = 'Řez - transverzální rovina';
            app.b0RefButton = uibutton(app.ADCmapaTab, 'push');
            app.b0RefButton.ButtonPushedFcn = createCallbackFcn(app, @b0RefLoad, true);
            app.b0RefButton.Tooltip = {'Načtení snímků bez difuzního vážení'};
            app.b0RefButton.Text = {'Načíst b0'; 'data'};
            app.rezVyber = uislider(app.ADCmapaTab);
            app.rezVyber.Limits = [1 80];
            app.rezVyber.MajorTicks = [1 40 80];
            app.rezVyber.ValueChangedFcn = createCallbackFcn(app, @rezVyberValueChanged, true);
            app.rezVyber.ValueChangingFcn = createCallbackFcn(app, @rezVyberValueChanging, true);
            app.rezVyber.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80];
            app.rezVyber.Tooltip = {'Před spuštěním výpočtu potřeba zvolit řez'};
            app.rezVyber.Value = 1;
            app.saveFile = uibutton(app.ADCmapaTab, 'push');
            app.saveFile.ButtonPushedFcn = createCallbackFcn(app, @saveFileButtonPushed, true);
            app.saveFile.Tooltip = {'Uložení ADC mapy do formátu Nifti'};
            app.saveFile.Text = 'Uložit';
            app.rezVyberSagCor = uislider(app.ADCmapaTab);
            app.rezVyberSagCor.Limits = [1 192];
            app.rezVyberSagCor.MajorTicks = [1 96 192];
            app.rezVyberSagCor.ValueChangedFcn = createCallbackFcn(app, @rezVyberSagCorValueChanged, true);
            app.rezVyberSagCor.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];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
            app.rezVyberSagCor.Tooltip = {'Před spuštěním výpočtu potřeba zvolit řez'};
            app.rezVyberSagCor.Value = 1;
            app.popisekCorSagSlider = uilabel(app.ADCmapaTab);
            app.popisekCorSagSlider.Text = 'Řez - sagitální a koronární rovina';
            app.rezTransverzalniCislo = uispinner(app.ADCmapaTab);
            app.rezTransverzalniCislo.Limits = [1 80];
            app.rezTransverzalniCislo.ValueChangedFcn = createCallbackFcn(app, @rezTransverzalniCisloValueChanged, true);
            app.rezTransverzalniCislo.Value = 1;
            app.rezSagitKorCislo = uispinner(app.ADCmapaTab);
            app.rezSagitKorCislo.Limits = [1 192];
            app.rezSagitKorCislo.ValueChangedFcn = createCallbackFcn(app, @rezSagitKorCisloValueChanged, true);
            app.rezSagitKorCislo.Value = 1;
            app.vyberOkna = uidropdown(app.ADCmapaTab);
            app.vyberOkna.Items = {'Transverzální', 'Koronární', 'Sagitální'};
            app.vyberOkna.ValueChangedFcn = createCallbackFcn(app, @vyberOknaValueChanged, true);
            app.vyberOkna.Value = 'Transverzální';
            app.Konzole = uitextarea(app.ADCmapaTab);
            app.Konzole.Editable = 'off';
            app.Konzole.Tooltip = {'Konzole'};
            app.Image = uiimage(app.ADCmapaTab);
            app.Image.ImageSource = '450 FEI-CZ.png';
            app.bValHodnota = uispinner(app.ADCmapaTab);
            app.bValHodnota.Step = 100;
            app.bValHodnota.Limits = [1 4000];
            app.bValHodnota.Tooltip = {'Akviziční hodnota b value'};
            app.bValHodnota.Value = 1000;
            app.vyberOrganu = uidropdown(app.ADCmapaTab);
            app.vyberOrganu.Items = {'Mozek', 'Prostata'};
            app.vyberOrganu.ValueChangedFcn = createCallbackFcn(app, @vyberOrganuValueChanged, true);
            app.vyberOrganu.Value = 'Mozek';
            app.AkvizinhodnotabvalueLabel = uilabel(app.ADCmapaTab);
            app.AkvizinhodnotabvalueLabel.Text = 'Akviziční hodnota b value';
            app.objem4DdatasetLabel = uilabel(app.ADCmapaTab);
            app.objem4DdatasetLabel.Visible = 'off';
            app.objem4DdatasetLabel.Text = 'Objem z 4D datasetu';
            app.LanguageDropDownLabel = uilabel(app.ADCmapaTab);
            app.LanguageDropDownLabel.Text = 'Language';
            app.Language = uidropdown(app.ADCmapaTab);
            app.Language.Items = {'Česky', 'English'};
            app.Language.ValueChangedFcn = createCallbackFcn(app, @LanguageValueChanged, true);
            app.Language.Value = 'Česky';
            app.HypersignlnstiTab = uitab(app.TabGroup);
            app.HypersignlnstiTab.Title = 'Hypersignální části';
            app.horizHyper = uiaxes(app.HypersignlnstiTab);
            title(app.horizHyper, 'ADC mapa, sagitální')
            zlabel(app.horizHyper, 'Z')
            app.horizHyper.Visible = 'off';
            app.frontalHyper = uiaxes(app.HypersignlnstiTab);
            title(app.frontalHyper, 'ADC mapa, transverzální')
            zlabel(app.frontalHyper, 'Z')
            app.sagitHyper = uiaxes(app.HypersignlnstiTab);
            title(app.sagitHyper, 'ADC mapa, koronární')
            zlabel(app.sagitHyper, 'Z')
            app.sagitHyper.Visible = 'off';
            app.kvantilValue = uispinner(app.HypersignlnstiTab);
            app.kvantilValue.Step = 0.05;
            app.kvantilValue.Limits = [0 1];
            app.kvantilValue.ValueDisplayFormat = '%5.2f';
            app.kvantilValue.ValueChangedFcn = createCallbackFcn(app, @kvantilValueValueChanged, true);
            app.kvantilValue.Value = 0.9;
            app.popisekKvantil = uilabel(app.HypersignlnstiTab);
            app.popisekKvantil.Text = 'Kvantil';
            app.hyperSigButton = uibutton(app.HypersignlnstiTab, 'push');
            app.hyperSigButton.ButtonPushedFcn = createCallbackFcn(app, @hyperSigButtonPushed, true);
            app.hyperSigButton.Text = {'Zobrazit'; 'hypersignální'; 'části'};
            app.vyberOkna2 = uidropdown(app.HypersignlnstiTab);
            app.vyberOkna2.Items = {'Transverzální', 'Koronární', 'Sagitální'};
            app.vyberOkna2.ValueChangedFcn = createCallbackFcn(app, @vyberOkna2ValueChanged, true);
            app.vyberOkna2.Value = 'Transverzální';
            app.Image_2 = uiimage(app.HypersignlnstiTab);
            app.Image_2.ImageSource = '450 FEI-CZ.png';
            app.Konzole_2 = uitextarea(app.HypersignlnstiTab);
            app.Konzole_2.Editable = 'off';
            app.Konzole_2.Tooltip = {'Konzole'};
            app.HypersignlnhyposignlnloiskaSwitchLabel = uilabel(app.HypersignlnstiTab);
            app.HypersignlnhyposignlnloiskaSwitchLabel.Text = {'Hypersignální/ '; 'hyposignální ložiska'};
            app.hyperHypoSwitch = uiswitch(app.HypersignlnstiTab, 'slider');
            app.hyperHypoSwitch.Items = {'Hypersignální', 'Hyposignální'};
            app.hyperHypoSwitch.Value = 'Hypersignální';
            app.popisekAxialSlider_2 = uilabel(app.HypersignlnstiTab);
            app.popisekAxialSlider_2.Text = 'Řez - transverzální rovina';
            app.rezVyberHyper = uislider(app.HypersignlnstiTab);
            app.rezVyberHyper.Limits = [1 80];
            app.rezVyberHyper.MajorTicks = [1 40 80];
            app.rezVyberHyper.ValueChangedFcn = createCallbackFcn(app, @rezVyberHyperValueChanged, true);
            app.rezVyberHyper.ValueChangingFcn = createCallbackFcn(app, @rezVyberHyperValueChanging, true);
            app.rezVyberHyper.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80];
            app.rezVyberHyper.Tooltip = {'Před spuštěním výpočtu potřeba zvolit řez'};
            app.rezVyberHyper.Value = 1;
            app.rezTransverzalniCisloHyper = uispinner(app.HypersignlnstiTab);
            app.rezTransverzalniCisloHyper.Limits = [1 80];
            app.rezTransverzalniCisloHyper.ValueChangedFcn = createCallbackFcn(app, @rezTransverzalniCisloHyperValueChanged, true);
            app.rezTransverzalniCisloHyper.Value = 1;
            app.VbrROITab = uitab(app.TabGroup);
            app.VbrROITab.Title = 'Výběr ROI';
            app.vybraneRoi = uiaxes(app.VbrROITab);
            title(app.vybraneRoi, 'Vybrané ROI')
            zlabel(app.vybraneRoi, 'Z')
            app.boxplotFig = uiaxes(app.VbrROITab);
            title(app.boxplotFig, 'Boxplot jasových intenzit')
            zlabel(app.boxplotFig, 'Z')
            app.VbrROISwitchLabel = uilabel(app.VbrROITab);
            app.VbrROISwitchLabel.Text = 'Výběr ROI';
            app.VbrROISwitch = uiswitch(app.VbrROITab, 'slider');
            app.VbrROISwitch.Items = {'Ne', 'Ano'};
            app.VbrROISwitch.ValueChangedFcn = createCallbackFcn(app, @VbrROISwitchValueChanged, true);
            app.VbrROISwitch.Value = 'Ne';
            app.nacistDalsiData = uibutton(app.VbrROITab, 'push');
            app.nacistDalsiData.ButtonPushedFcn = createCallbackFcn(app, @nacistDalsiDataButtonPushed, true);
            app.nacistDalsiData.Tooltip = {'Načtení dalších ADC map pro porovnání jasových intenzit v ROI'};
            app.nacistDalsiData.Text = {'Načíst ADC z'; 'předchozích'; 'snímkování'};
            app.VbrvcesouborproporovnnSwitchLabel = uilabel(app.VbrROITab);
            app.VbrvcesouborproporovnnSwitchLabel.Text = {'Výběr více'; 'souborů pro'; 'porovnání'};
            app.multiChoiceSwitch = uiswitch(app.VbrROITab, 'slider');
            app.multiChoiceSwitch.Items = {'Ne', 'Ano'};
            app.multiChoiceSwitch.Tooltip = {'Před výběrem souborů je potřeba zvolit ''Ano'' pro výběr několika souborů, případně přepnout zpět na ''Ne'', jinak aplikace nebude fungovat'};
            app.multiChoiceSwitch.Value = 'Ne';
            app.Image_3 = uiimage(app.VbrROITab);
            app.Image_3.ImageSource = '450 FEI-CZ.png';
            % Show the figure after all components are created
            app.adcMapGui.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adcMapTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.adcMapGui)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.adcMapGui)
        end
    end
end